classdef InterfaceG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        PlotarimagemButton          matlab.ui.control.Button
        AdicionarButton             matlab.ui.control.Button
        readediasselecionadosLabel  matlab.ui.control.Label
        DiasTextArea                matlab.ui.control.TextArea
        GerarMatrizButton           matlab.ui.control.Button
        NmerodaPCALabel             matlab.ui.control.Label
        PCAEditField                matlab.ui.control.NumericEditField
        DiasEditField               matlab.ui.control.EditField
        SalvarMatrizButton          matlab.ui.control.Button
        DigiteosdiasdesejadosLabel  matlab.ui.control.Label
        SelecionarMatrizButton      matlab.ui.control.Button
        SelecionarPastacomosdadosButton  matlab.ui.control.Button
        SelecionarMesInteiroButton  matlab.ui.control.Button
        AnoLabel                    matlab.ui.control.Label
        AnoEditField                matlab.ui.control.NumericEditField
        ExcluirButton               matlab.ui.control.Button
        ExcluirdiaEditFieldLabel    matlab.ui.control.Label
        ExcluirDiaEditField         matlab.ui.control.EditField
        RightPanel                  matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
        PaisesLabel                 matlab.ui.control.Label
        PaisesDropDown              matlab.ui.control.DropDown
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = public)
        matrix_list             % Matriz de dados final para o PCA
        EstacoesAtivas = {}     % Siglas das estações válidas encontradas
        DiasSelecionados = []   % Vetor com os dias escolhidos
        PastaSelecionada = ''   % Caminho da pasta com os arquivos TXT
        dadosRegiao             % Struct com limites geográficos, lats, lons e shapefile
        nomes_totais            % IMPORTANTE: Lista mestre de estações do país ativo
    end
    

    methods (Access = private)
        
        % Alterna a seleção de uma estação manualmente clicando no mapa
        function cliqueNaEstacao(app, sigla)
            if ismember(sigla, app.EstacoesAtivas)
                app.EstacoesAtivas(strcmp(app.EstacoesAtivas, sigla)) = [];
            else
                app.EstacoesAtivas{end+1} = sigla;
            end
            app.atualizarMapa();
        end

        % Redesenha as bordas e as estações na tela
        function atualizarMapa(app)
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
        
            % 1. Plota o Shapefile do país atual de forma vetorizada rápida
            if isfield(app.dadosRegiao, 'shapefile') && exist(app.dadosRegiao.shapefile, 'file')
                try
                    S = shaperead(app.dadosRegiao.shapefile);
                    if isfield(S, 'X'), lonF = 'X'; latF = 'Y';
                    elseif isfield(S, 'Lon'), lonF = 'Lon'; latF = 'Lat';
                    else, lonF = ''; end
        
                    if ~isempty(lonF)
                        allLon = [S.(lonF)];
                        allLat = [S.(latF)];
                        plot(app.UIAxes, allLon, allLat, 'Color', [0.3 0.3 0.3], 'LineWidth', 1, 'HitTest', 'off');
                    end
                catch
                    fprintf('Aviso: Erro ao ler shapefile %s\n', app.dadosRegiao.shapefile);
                end
            end
        
            % 2. Resgata e plota todas as estações base do país (Pontos Vermelhos)
            if isfield(app.dadosRegiao, 'lat') && ~isempty(app.dadosRegiao.lat)
                lats = app.dadosRegiao.lat;
                lons = app.dadosRegiao.lon;
                siglas = app.dadosRegiao.estacoes;
        
                plot(app.UIAxes, lons, lats, 'ro', 'MarkerSize', 5, 'MarkerFaceColor', 'r', 'MarkerEdgeColor', 'k');
                
                % 3. Destaca em Verde as estações obtidas pelo arquivo/processamento
                if ~isempty(app.EstacoesAtivas)
                    [presente, idxAtivas] = ismember(app.EstacoesAtivas, siglas);
                    idxValidos = idxAtivas(presente); 
                    if ~isempty(idxValidos)
                        plot(app.UIAxes, lons(idxValidos), lats(idxValidos), 'go', 'MarkerSize', 6, 'MarkerFaceColor', 'g', 'MarkerEdgeColor', 'k');
                    end
                end
            end
        
            % 4. Aplica travas geográficas de zoom baseadas no País ativo
            xlim(app.UIAxes, app.dadosRegiao.xlim);
            ylim(app.UIAxes, app.dadosRegiao.ylim);
            axis(app.UIAxes, 'equal');
            grid(app.UIAxes, 'on');
            hold(app.UIAxes, 'off');
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if exist('estacoes_coords.txt', 'file')
                tabela = readtable('estacoes_coords.txt');
            else
                uialert(app.UIFigure, 'Arquivo mestre estacoes_coords.txt não encontrado!', 'Erro Crítico');
                return;
            end
            
            % Reset inicial do ambiente de dados
            app.matrix_list = [];
            app.EstacoesAtivas = {}; 
            app.dadosRegiao = struct();
            app.PaisesDropDown.Value = 'Brasil';
            
            % Inicializa focando no Brasil (Estações do índice 1 a 65)
            tabela_br = tabela(1:65, :);
            app.dadosRegiao.estacoes = tabela_br.Sigla;
            app.dadosRegiao.lat = tabela_br.Latitude;
            app.dadosRegiao.lon = tabela_br.Longitude;
            app.dadosRegiao.xlim = [-75 -34];
            app.dadosRegiao.ylim = [-35 7];
            app.dadosRegiao.shapefile = 'Brasil.shp';
            
            app.nomes_totais = tabela_br.Sigla;
            app.UIFigure.Name = 'PCA - Principal Component Analysis (TEC)';
            app.atualizarMapa();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {630, 630};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {302, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: PlotarimagemButton
        function PlotarimagemButtonPushed(app, event)
            if isempty(app.matrix_list)
                uialert(app.UIFigure, 'A matriz está vazia! Gere os dados antes de plotar.', 'Aviso');
                return;
            end
            
            n_dias = length(app.DiasSelecionados);
            n_estacoes = length(app.EstacoesAtivas);
            minutos_por_dia = 1440;
            tamanho_minutos = n_dias * minutos_por_dia;
            
            [nL, nC] = size(app.matrix_list);
            
            fprintf('DEBUG Plotar: matriz %dx%d | esperado minutos=%d | estacoes=%d\n', ...
                nL, nC, tamanho_minutos, n_estacoes);
            
            % --- Conversão de formato se necessário ---
            if nL == tamanho_minutos && nC == n_estacoes
                % Formato [minutos, estacoes] → converte para [estacoes, dias]
                fprintf('DEBUG: Convertendo [minutos x estacoes] -> [estacoes x dias]\n');
                matriz_convertida = zeros(n_estacoes, n_dias);
                for dia_idx = 1:n_dias
                    inicio = (dia_idx-1)*minutos_por_dia + 1;
                    fim    = dia_idx * minutos_por_dia;
                    % nanmean ignora NaNs parciais sem cancelar o dia inteiro
                    matriz_convertida(:, dia_idx) = nanmean(app.matrix_list(inicio:fim, :), 1)';
                end
                app.matrix_list = matriz_convertida;
            
            elseif nC == tamanho_minutos && nL == n_estacoes
                % Formato transposto [estacoes, minutos]
                fprintf('DEBUG: Convertendo [estacoes x minutos] -> [estacoes x dias]\n');
                matriz_convertida = zeros(n_estacoes, n_dias);
                for dia_idx = 1:n_dias
                    inicio = (dia_idx-1)*minutos_por_dia + 1;
                    fim    = dia_idx * minutos_por_dia;
                    matriz_convertida(:, dia_idx) = nanmean(app.matrix_list(:, inicio:fim), 2);
                end
                app.matrix_list = matriz_convertida;
            
            elseif nL == n_estacoes && nC == n_dias
                % Já está no formato correto [estacoes, dias]
                fprintf('DEBUG: Matriz já está no formato [estacoes x dias]. Sem conversão.\n');
            
            else
                % Dimensões inesperadas — avisa mas NÃO bloqueia, tenta assim mesmo
                fprintf('AVISO: Dimensões inesperadas [%d x %d]. Tentando prosseguir.\n', nL, nC);
            end
            
            % Validação final: só cancela se a matriz ficou 100% NaN
            if all(isnan(app.matrix_list(:)))
                uialert(app.UIFigure, ...
                    'A matriz está completamente vazia (só NaN). Verifique os dados dos arquivos TXT.', ...
                    'Erro');
                return;
            end
            
            try
                nEOF = app.PCAEditField.Value;
                if isempty(nEOF) || isnan(nEOF) || nEOF <= 0
                    nEOF = 1;
                    app.PCAEditField.Value = 1;
                end
            
                nomes_mestre_fixo  = cellstr(app.nomes_totais);
                estacoes_ativas_fixo = cellstr(app.EstacoesAtivas);
                lat_fixo = app.dadosRegiao.lat;
                lon_fixo = app.dadosRegiao.lon;
            
                fprintf('DEBUG: Chamando calcularPCAApp com matriz %dx%d\n', size(app.matrix_list,1), size(app.matrix_list,2));
            
                % Calcula a PCA e extrai as matrizes interpoladas e as posições
                [LonG, LatG, Z, lat_pts, lon_pts] = calcularPCAApp(...
                    app.matrix_list, ...
                    nomes_mestre_fixo, ...
                    estacoes_ativas_fixo, ...
                    nEOF, ...
                    lat_fixo, ...
                    lon_fixo);
            
                % --- DENTRO DA FUNÇÃO DE PLOTAGEM DA INTERFACE ---
                
                % 1. Define dinamicamente os limites geográficos e o arquivo .shp pelo DropDown
                pais_atual = app.PaisesDropDown.Value; % 'Brasil' ou 'Argentina'
                
                switch pais_atual
                    case 'Brasil'
                        limites_lon = [-75, -34];  % Abrange todo o território brasileiro
                        limites_lat = [-35, 6];
                        arquivo_shp = 'brasil.shp'; % Certifique-se de ter esse arquivo na pasta do script
                    case 'Argentina'
                        limites_lon = [-74, -53];  % Abrange o território argentino
                        limites_lat = [-56, -21];
                        arquivo_shp = 'argentina.shp'; % Certifique-se de ter esse arquivo na pasta do script
                    otherwise
                        % Configuração padrão para a América do Sul como um todo
                        limites_lon = [-90, -30];
                        limites_lat = [-60, 15];
                        arquivo_shp = '';
                end
                
                % 2. Limpa os eixos da interface antes de desenhar o novo mapa
                cla(app.UIAxes);
                
                % 3. Plota o mapa de calor da interpolação (Z) direcionado para os eixos da interface
                contourf(app.UIAxes, LonG, LatG, Z, 50, 'LineColor', 'none');
                hold(app.UIAxes, 'on'); % Mantém o fundo colorido para desenhar por cima
                
                % 4. CORREÇÃO CRÍTICA DO ACHATAMENTO
                % Trava a proporção dos eixos em 1:1 (1 grau de latitude = 1 grau de longitude na tela)
                axis(app.UIAxes, 'equal'); 
                % Força o zoom gráfico exatamente na região do país correspondente
                xlim(app.UIAxes, limites_lon);
                ylim(app.UIAxes, limites_lat);
                
                % 5. CORREÇÃO CRÍTICA DO CONTORNO DO PAÍS
                if ~isempty(arquivo_shp) && isfile(arquivo_shp)
                    % Lê as coordenadas estruturadas do shapefile
                    dados_mapa = shaperead(arquivo_shp);
                    % Desenha a linha de contorno em preto ('k-') com espessura visível (1.5)
                    plot(app.UIAxes, [dados_mapa.X], [dados_mapa.Y], 'k-', 'LineWidth', 1.5);
                else
                    % Aviso caso o arquivo .shp correspondente não esteja na mesma pasta do projeto
                    warning('Aviso: O arquivo de mapa "%s" não foi encontrado na pasta atual.', arquivo_shp);
                end
                
                % 6. Plota a posição física das estações ativas como referência (pontos pretos)
                if exist('lon_pts', 'var') && exist('lat_pts', 'var')
                    scatter(app.UIAxes, lon_pts, lat_pts, 20, 'k', 'filled');
                end
                
                % 7. Ajustes estéticos finais de escala e cores
                colormap(app.UIAxes, 'jet');
                c = colorbar(app.UIAxes);
                c.Label.String = 'Variação TEC'; % Adicionado para manter o padrão das suas visualizações
                title(app.UIAxes, sprintf('EOF %d - %s', nEOF, pais_atual)); % Mantém o título dinâmico
                grid(app.UIAxes, 'on');
                
                hold(app.UIAxes, 'off'); % Libera os eixos para a próxima plotagem
            
            catch ME
                fprintf('DEBUG Erro PCA: %s\n', ME.message);
                for k = 1:length(ME.stack)
                    fprintf('  %s (linha %d)\n', ME.stack(k).name, ME.stack(k).line);
                end
                uialert(app.UIFigure, ['Erro na plotagem PCA: ' ME.message], 'Erro Gráfico');
                hold(app.UIAxes, 'off');
            end
        end

        % Button pushed function: AdicionarButton
        function AdicionarButtonPushed(app, event)
            app.GerarMatrizButtonPushed([]);
        end

        % Button pushed function: GerarMatrizButton
        function GerarMatrizButtonPushed(app, event)
            if isempty(app.PastaSelecionada)
                uialert(app.UIFigure, 'Selecione a pasta com os dados .txt primeiro!', 'Erro');
                return;
            end
            if isempty(app.DiasSelecionados)
                uialert(app.UIFigure, 'Adicione pelo menos um dia!', 'Erro');
                return;
            end
        
            ano = app.AnoEditField.Value;
            if isempty(ano) || isnan(ano)
                uialert(app.UIFigure, 'Digite um ano válido.', 'Erro');
                return;
            end
        
            try
                app.GerarMatrizButton.Text = 'Buscando Dados...';
                app.GerarMatrizButton.Enable = 'off';
                drawnow;
        
                % Força a verificação das estações da região ativa do app
                estacoes_filtro = app.dadosRegiao.estacoes;
                
                % DEBUG: Mostra informações antes de processar
                fprintf('DEBUG: Pasta: %s\n', app.PastaSelecionada);
                fprintf('DEBUG: Dias: %s\n', mat2str(app.DiasSelecionados));
                fprintf('DEBUG: Ano: %d\n', ano);
                fprintf('DEBUG: Estações filtro: %d\n', length(estacoes_filtro));
        
                % Executa a função corrigida
                [matriz_temp, siglas] = gerar_matriz_TEC(...
                    app.PastaSelecionada, ...
                    app.DiasSelecionados, ...
                    estacoes_filtro, ...
                    ano);
                
                % DEBUG: Mostra dimensões da matriz gerada
                fprintf('DEBUG: Dimensões da matriz gerada: %dx%d\n', size(matriz_temp,1), size(matriz_temp,2));
                fprintf('DEBUG: Número de estações encontradas: %d\n', length(siglas));
                
                % Validação pós-processamento
                if isempty(matriz_temp) || size(matriz_temp, 2) == 0
                    uialert(app.UIFigure, 'A matriz gerada está vazia. Verifique os nomes dos arquivos TXT.', 'Erro de Leitura');
                    app.GerarMatrizButton.Text = 'Gerar Matriz';
                    app.GerarMatrizButton.Enable = 'on';
                    return;
                end
                
                if size(matriz_temp, 2) == 0
                    uialert(app.UIFigure, 'A matriz gerada não tem colunas. Verifique os dias selecionados.', 'Erro de Leitura');
                    app.GerarMatrizButton.Text = 'Gerar Matriz';
                    app.GerarMatrizButton.Enable = 'on';
                    return;
                end
                
                if size(matriz_temp, 1) == 0
                    uialert(app.UIFigure, 'A matriz gerada não tem linhas. Nenhuma estação encontrada.', 'Erro de Leitura');
                    app.GerarMatrizButton.Text = 'Gerar Matriz';
                    app.GerarMatrizButton.Enable = 'on';
                    return;
                end
                
                if all(isnan(matriz_temp(:)))
                    uialert(app.UIFigure, 'A matriz gerada contém apenas NaN. Verifique os dados dos arquivos.', 'Erro de Leitura');
                    app.GerarMatrizButton.Text = 'Gerar Matriz';
                    app.GerarMatrizButton.Enable = 'on';
                    return;
                end
        
                % Armazena nas propriedades globais do App
                app.matrix_list = matriz_temp;
                app.EstacoesAtivas = cellstr(siglas);
                                 
                fprintf('DEBUG: matrix_list salva com dimensões: %dx%d\n', size(app.matrix_list,1), size(app.matrix_list,2));
                fprintf('DEBUG: EstacoesAtivas salvas: %d\n', length(app.EstacoesAtivas));
        
                uialert(app.UIFigure, sprintf('Sucesso! Matriz gerada com %d estações encontradas.', length(app.EstacoesAtivas)), 'Concluído');
                
                % Atualiza o mapa
                app.atualizarMapa();
                
            catch ME
%                 uialert(app.UIFigure, ['Falha ao carregar matriz: ' ME.message], 'Erro Crítico');
                fprintf('DEBUG: ERRO - %s\n', ME.message);
                fprintf('DEBUG: Stack trace:\n');
                for k = 1:length(ME.stack)
                    fprintf('  %s (linha %d)\n', ME.stack(k).name, ME.stack(k).line);
                end
                uialert(app.UIFigure, ['Falha ao carregar matriz: ' ME.message], 'Erro Crítico');
            end
            app.GerarMatrizButton.Text = 'Gerar Matriz';
            app.GerarMatrizButton.Enable = 'on';
        end

        % Button pushed function: SalvarMatrizButton
        function SalvarMatrizButtonPushed(app, event)
            if isempty(app.matrix_list)
                uialert(app.UIFigure, ...
                    'Não há nenhuma matriz gerada ativa para salvar!', ...
                    'Aviso');
                return;
            end
        
            sugestao = sprintf('Matriz_%s_%d.txt', ...
                app.PaisesDropDown.Value, ...
                int32(app.AnoEditField.Value));
        
            [arquivo, caminho] = uiputfile('*.txt', ...
                'Salvar Matriz Calculada', ...
                sugestao);
        
            if isequal(arquivo,0)
                return;
            end
        
            try
        
                fid = fopen(fullfile(caminho,arquivo),'w');
        
                if fid == -1
                    error('Não foi possível criar o arquivo.');
                end
        
                for i = 1:size(app.matrix_list,1)
                    fprintf(fid,'%.15g ',app.matrix_list(i,:));
                    fprintf(fid,'\n');
                end
        
                fclose(fid);
        
                uialert(app.UIFigure, ...
                    'Arquivo TXT salvo com sucesso!', ...
                    'Concluído');
        
            catch ME
        
                if exist('fid','var') && fid ~= -1
                    fclose(fid);
                end
        
                uialert(app.UIFigure, ...
                    ['Erro de gravação: ' ME.message], ...
                    'Erro');
            end
        end

        % Button pushed function: SelecionarMatrizButton
        function SelecionarMatrizButtonPushed(app, event)
        [arquivo, caminho] = uigetfile( ...
                '*.txt', ...
                'Selecione uma matriz existente');
        
            if isequal(arquivo, 0)
                return;
            end
        
            nome_completo = fullfile(caminho, arquivo);
        
            try
        
                % Lê a matriz do TXT
                app.matrix_list = readmatrix(nome_completo);
        
                % Verifica se carregou algo
                if isempty(app.matrix_list)
                    error('O arquivo está vazio ou não contém dados válidos.');
                end
        
                fprintf('Matriz carregada: %d x %d\n', ...
                    size(app.matrix_list,1), ...
                    size(app.matrix_list,2));
        
                uialert(app.UIFigure, ...
                    ['Matriz importada com sucesso: ' arquivo], ...
                    'Sucesso');
        
                % Atualiza o mapa, se necessário
                app.atualizarMapa();
        
            catch ME
        
                uialert(app.UIFigure, ...
                    ['Erro na importação: ' ME.message], ...
                    'Erro');
        
            end
        end

        % Value changed function: DiasEditField
        function DiasEditFieldValueChanged(app, event)
            dia_digitado = str2double(app.DiasEditField.Value);
            if isnan(dia_digitado) || dia_digitado < 1 || dia_digitado > 366
                uialert(app.UIFigure, 'Digite um dia de ano válido (1-366)!', 'Erro de Entrada');
                app.DiasEditField.Value = '';
                return;
            end
        
            if ~ismember(dia_digitado, app.DiasSelecionados)
                app.DiasSelecionados = sort([app.DiasSelecionados, dia_digitado]);
                app.DiasTextArea.Value = cellstr(num2str(app.DiasSelecionados', 'Dia %d'));
            end
            app.DiasEditField.Value = '';
        end

        % Button pushed function: SelecionarPastacomosdadosButton
        function SelecionarPastacomosdadosButtonPushed(app, event)
            caminho_pasta = uigetdir('', 'Selecione a PASTA contendo todos os arquivos .txt das estações');
            if isequal(caminho_pasta, 0), return; end
            
            arquivos_txt = dir(fullfile(caminho_pasta, '*.txt'));
            if isempty(arquivos_txt)
                uialert(app.UIFigure, 'Nenhum arquivo .txt encontrado na pasta selecionada!', 'Pasta Inválida');
                return;
            end
            
            app.PastaSelecionada = caminho_pasta;
            uialert(app.UIFigure, sprintf('Pasta vinculada! Encontrados %d arquivos .txt para processamento.', length(arquivos_txt)), 'Sucesso');
        end

        % Value changed function: PaisesDropDown
        function PaisesDropDownValueChanged(app, event)
            if ~exist('estacoes_coords.txt', 'file')
                uialert(app.UIFigure, 'Arquivo estacoes_coords.txt não encontrado!', 'Erro');
                return;
            end
            
            tabela = readtable('estacoes_coords.txt');
            val = app.PaisesDropDown.Value;
        
            if strcmp(val, 'Brasil')
                tabela_filtrada = tabela(1:65, :);
                app.dadosRegiao.xlim = [-75 -34];
                app.dadosRegiao.ylim = [-35 7];  
                app.dadosRegiao.shapefile = 'Brasil.shp';
            else
                % Argentina: Linhas do índice 66 em diante
                tabela_filtrada = tabela(66:end, :);
                app.dadosRegiao.xlim = [-76 -52];
                app.dadosRegiao.ylim = [-58 -20]; 
                app.dadosRegiao.shapefile = 'Argentina.shp';
            end
        
            app.dadosRegiao.estacoes = tabela_filtrada.Sigla;
            app.dadosRegiao.lat = tabela_filtrada.Latitude;
            app.dadosRegiao.lon = tabela_filtrada.Longitude;
            
            app.nomes_totais = tabela_filtrada.Sigla; 
            app.EstacoesAtivas = {};
            app.matrix_list = [];
            
            app.atualizarMapa();
        end

        % Button pushed function: SelecionarMesInteiroButton
        function SelecionarMesInteiroButtonPushed(app, event)
            app.DiasSelecionados = 1:31;
            app.DiasTextArea.Value = cellstr(num2str(app.DiasSelecionados', 'Dia %d'));
            app.DiasEditField.Value = '';
        end

        % Button pushed function: ExcluirButton
        function ExcluirButtonPushed(app, event)
            dia_para_remover = str2double(app.ExcluirDiaEditField.Value);
            if isnan(dia_para_remover), return; end
        
            if ismember(dia_para_remover, app.DiasSelecionados)
                app.DiasSelecionados(app.DiasSelecionados == dia_para_remover) = [];
                if isempty(app.DiasSelecionados)
                    app.DiasTextArea.Value = '';
                    app.matrix_list = [];
                else
                    app.DiasTextArea.Value = cellstr(num2str(app.DiasSelecionados', 'Dia %d'));
                end
                app.ExcluirDiaEditField.Value = '';
            else
                uialert(app.UIFigure, 'O dia informado não está na lista de análise.', 'Aviso');
            end
        end

        % Callback function
        function ExcluirDiaEditFieldValueChanged(app, event)
            % Só executa se o campo não estiver vazio (evita chamadas inúteis)
            if ~isempty(app.ExcluirDiaEditField.Value)
                % Chama a lógica principal de exclusão
                app.ExcluirButtonPushed([]); 
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.PlotarimagemButton = uibutton(app.LeftPanel, 'push');
            app.PlotarimagemButton.ButtonPushedFcn = createCallbackFcn(app, @PlotarimagemButtonPushed, true);
            app.PlotarimagemButton.Text = 'Plotar imagem';
            app.AdicionarButton = uibutton(app.LeftPanel, 'push');
            app.AdicionarButton.ButtonPushedFcn = createCallbackFcn(app, @AdicionarButtonPushed, true);
            app.AdicionarButton.Text = 'Adicionar';
            app.readediasselecionadosLabel = uilabel(app.LeftPanel);
            app.readediasselecionadosLabel.Text = 'Área de dias selecionados';
            app.DiasTextArea = uitextarea(app.LeftPanel);
            app.GerarMatrizButton = uibutton(app.LeftPanel, 'push');
            app.GerarMatrizButton.ButtonPushedFcn = createCallbackFcn(app, @GerarMatrizButtonPushed, true);
            app.GerarMatrizButton.Text = 'Gerar Matriz';
            app.NmerodaPCALabel = uilabel(app.LeftPanel);
            app.NmerodaPCALabel.Text = 'Número da PCA :';
            app.PCAEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DiasEditField = uieditfield(app.LeftPanel, 'text');
            app.DiasEditField.ValueChangedFcn = createCallbackFcn(app, @DiasEditFieldValueChanged, true);
            app.SalvarMatrizButton = uibutton(app.LeftPanel, 'push');
            app.SalvarMatrizButton.ButtonPushedFcn = createCallbackFcn(app, @SalvarMatrizButtonPushed, true);
            app.SalvarMatrizButton.Text = 'Salvar Matriz';
            app.DigiteosdiasdesejadosLabel = uilabel(app.LeftPanel);
            app.DigiteosdiasdesejadosLabel.Text = 'Digite os dias desejados:';
            app.SelecionarMatrizButton = uibutton(app.LeftPanel, 'push');
            app.SelecionarMatrizButton.ButtonPushedFcn = createCallbackFcn(app, @SelecionarMatrizButtonPushed, true);
            app.SelecionarMatrizButton.Text = 'Selecionar Matriz';
            app.SelecionarPastacomosdadosButton = uibutton(app.LeftPanel, 'push');
            app.SelecionarPastacomosdadosButton.ButtonPushedFcn = createCallbackFcn(app, @SelecionarPastacomosdadosButtonPushed, true);
            app.SelecionarPastacomosdadosButton.Text = 'Selecionar Pasta com os dados';
            app.SelecionarMesInteiroButton = uibutton(app.LeftPanel, 'push');
            app.SelecionarMesInteiroButton.ButtonPushedFcn = createCallbackFcn(app, @SelecionarMesInteiroButtonPushed, true);
            app.SelecionarMesInteiroButton.Text = 'Mês Inteiro';
            app.AnoLabel = uilabel(app.LeftPanel);
            app.AnoLabel.Text = 'Ano';
            app.AnoEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ExcluirButton = uibutton(app.LeftPanel, 'push');
            app.ExcluirButton.ButtonPushedFcn = createCallbackFcn(app, @ExcluirButtonPushed, true);
            app.ExcluirButton.Text = 'Excluir';
            app.ExcluirdiaEditFieldLabel = uilabel(app.LeftPanel);
            app.ExcluirdiaEditFieldLabel.Text = 'Excluir dia:';
            app.ExcluirDiaEditField = uieditfield(app.LeftPanel, 'text');
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Estações ')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.PaisesLabel = uilabel(app.RightPanel);
            app.PaisesLabel.Text = 'Paises';
            app.PaisesDropDown = uidropdown(app.RightPanel);
            app.PaisesDropDown.Items = {'Brasil', 'Argentina'};
            app.PaisesDropDown.ValueChangedFcn = createCallbackFcn(app, @PaisesDropDownValueChanged, true);
            app.PaisesDropDown.Value = 'Brasil';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InterfaceG

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end